classdef Practica3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DesplazamientoSpinner       matlab.ui.control.Spinner
        DesplazamientoSpinnerLabel  matlab.ui.control.Label
        FrecuenciademuestreoporsegundoSpinner  matlab.ui.control.Spinner
        FrecuenciademuestreoporsegundoSpinnerLabel  matlab.ui.control.Label
        AmplitudSpinner             matlab.ui.control.Spinner
        AmplitudSpinnerLabel        matlab.ui.control.Label
        FrecuenciaSpinner           matlab.ui.control.Spinner
        FrecuenciaSpinnerLabel      matlab.ui.control.Label
        TiempodelasealLabel         matlab.ui.control.Label
        GeneradordesealesLabel      matlab.ui.control.Label
        tfEditField                 matlab.ui.control.NumericEditField
        tfEditFieldLabel            matlab.ui.control.Label
        toEditField                 matlab.ui.control.NumericEditField
        toEditFieldLabel            matlab.ui.control.Label
        DropDownSenales             matlab.ui.control.DropDown
        SealLabel                   matlab.ui.control.Label
        ButtonGenerar               matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        signalPlot = []; % Para almacenar la última grafica generada
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ButtonGenerar, UIAxes
        function ButtonGenerarPushed(app, event)
            f=app.FrecuenciaSpinner.Value; %Se obtiene la frecuencia del slider
            ampli=app.AmplitudSpinner.Value; %Se obtiene la amplitud del slider
            % Crear las señales continuas o discretas
            x=app.toEditField.Value:0.01:app.tfEditField.Value;
            xn=app.toEditField.Value:app.FrecuenciademuestreoporsegundoSpinner.Value:app.tfEditField.Value;

            switch app.DropDownSenales.Value

                    case 'Pulso rectangular'
                        
                        %Pulso rectangular continuo
                        y = ampli * (abs(x+ app.DesplazamientoSpinner.Value) < f/2);

                        %Pulso rectangular discreto
                        yn = ampli * (abs(xn + app.DesplazamientoSpinner.Value) < f/2);

                        % Graficar ambas señales
                        plot(app.UIAxes,x, y, 'b', 'LineWidth', 2);
                        title(app.UIAxes,'Señal Continua f(t) - Pulso rectangular');
                        xlabel(app.UIAxes,'Tiempo (s)');
                        ylabel(app.UIAxes,'Amplitud (m)');
                        
                        stem(app.UIAxes2,xn, yn, 'r', 'LineWidth', 2);
                        title(app.UIAxes2,'Señal Discreta f[t] - Pulso rectangular');
                        xlabel(app.UIAxes2,'Tiempo (s)');
                        ylabel(app.UIAxes2,'Amplitud (m)');

                    case 'Escalón unitario'                        
                        % Definir la señal continua f(t)
                        y = ampli * (x + app.DesplazamientoSpinner.Value >= 0);
                        
                        % Definir la señal discreta f[t]
                        yn = ampli * (xn + app.DesplazamientoSpinner.Value >= 0);
                        
                        % Graficar ambas señales
                        plot(app.UIAxes,x, y, 'b', 'LineWidth', 2);
                        title(app.UIAxes,'Señal Continua f(t) - Escalón Unitario');
                        xlabel(app.UIAxes,'Tiempo (s)');
                        ylabel(app.UIAxes,'Amplitud (m)');
                        
                        stem(app.UIAxes2,xn, yn, 'r', 'LineWidth', 2);
                        title(app.UIAxes2,'Señal Discreta f[t] - Escalón Unitario');
                        xlabel(app.UIAxes2,'Tiempo (s)');
                        ylabel(app.UIAxes2,'Amplitud (m)');
                        
                    case 'Impulso unitario'
                        y = ampli * (x + app.DesplazamientoSpinner.Value== 0);
                        yn = ampli * (xn + app.DesplazamientoSpinner.Value== 0);

                        % Graficar ambas señales
                        plot(app.UIAxes,x, y); % Grafica la señal continua
                        title(app.UIAxes,'Señal Continua f(t) - Impulso Unitario'); % Título de la subtrama
                        xlabel(app.UIAxes,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes,'Amplitud (m)'); % Etiqueta del eje y
                        
                        stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                        title(app.UIAxes2,'Señal Discreta f[t] - Impulso Unitario');
                        xlabel(app.UIAxes2,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes2,'Amplitud (m)'); % Etiqueta del eje y
                    

                    case 'Pulso triangular'
                        
                        %Pulso triangular
                        y = (1 - abs(x + app.DesplazamientoSpinner.Value)/f) .* (abs(x + app.DesplazamientoSpinner.Value) <= f);
                        yn = (1 - abs(xn + app.DesplazamientoSpinner.Value)/f) .* (abs(xn + app.DesplazamientoSpinner.Value) <= f);
                        
                        % Graficar ambas señales
                        plot(app.UIAxes,x, y); % Grafica la señal continua
                        title(app.UIAxes,'Señal Continua f(t) - Pulso Triangular'); % Título de la subtrama
                        xlabel(app.UIAxes,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes,'Amplitud (m)'); % Etiqueta del eje y
                        
                        stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                        title(app.UIAxes2,'Señal Discreta f[t] - Pulso Triangular');
                        xlabel(app.UIAxes2,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes2,'Amplitud (m)'); % Etiqueta del eje y

                    case 'Rampa'

                        y = ampli * (x + app.DesplazamientoSpinner.Value).* (x + app.DesplazamientoSpinner.Value >= 0);
                        yn = ampli * (xn + app.DesplazamientoSpinner.Value) .* (xn + app.DesplazamientoSpinner.Value >= 0);
                        % Graficar ambas señales
                        plot(app.UIAxes,x, y); % Grafica la señal continua
                        title(app.UIAxes,'Señal Continua f(t) - Rampa'); % Título de la subtrama
                        xlabel(app.UIAxes,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes,'Amplitud (m)'); % Etiqueta del eje y
                        
                        stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                        title(app.UIAxes2,'Señal Discreta f[t] - Rampa');
                        xlabel(app.UIAxes2,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes2,'Amplitud (m)'); % Etiqueta del eje y

                    case 'Senoidal'
                        y = ampli * sin(2*pi*f*(x + app.DesplazamientoSpinner.Value));
                        yn = ampli * sin(2*pi*f*(xn + app.DesplazamientoSpinner.Value));
                        % Graficar ambas señales
                        plot(app.UIAxes,x, y); % Grafica la señal continua
                        title(app.UIAxes,'Señal Continua f(t) - Senoidal'); % Título de la subtrama
                        xlabel(app.UIAxes,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes,'Amplitud (m)'); % Etiqueta del eje y
                        
                        stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                        title(app.UIAxes2,'Señal Discreta f[t] - Senoidal');
                        xlabel(app.UIAxes2,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes2,'Amplitud (m)'); % Etiqueta del eje y

                    case 'Senoidal compleja'
                        y = ampli * exp(1i*2*pi*f*(x + app.DesplazamientoSpinner.Value));
                        yn = ampli * exp(1i*2*pi*f*(xn + app.DesplazamientoSpinner.Value));
                        % Graficar ambas señales
                        plot(app.UIAxes,x, y); % Grafica la señal continua
                        title(app.UIAxes,'Señal Continua f(t) - Senoidal compleja'); % Título de la subtrama
                        xlabel(app.UIAxes,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes,'Amplitud (m)'); % Etiqueta del eje y
                        
                        stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                        title(app.UIAxes2,'Señal Discreta f[t] - Senoidal compleja');
                        xlabel(app.UIAxes2,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes2,'Amplitud (m)'); % Etiqueta del eje y

                    case 'Exponencial'
                        y = ampli * exp(-(x + app.DesplazamientoSpinner.Value));
                        yn = ampli * exp(-(xn+app.DesplazamientoSpinner.Value));
                        % Graficar ambas señales
                        plot(app.UIAxes,x, y); % Grafica la señal continua
                        title(app.UIAxes,'Señal Continua f(t) - Exponencial'); % Título de la subtrama
                        xlabel(app.UIAxes,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes,'Amplitud (m)'); % Etiqueta del eje y
                        
                        stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                        title(app.UIAxes2,'Señal Discreta f[t] - Exponencial');
                        xlabel(app.UIAxes2,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes2,'Amplitud (m)'); % Etiqueta del eje y

                    case 'Exponencial compleja'
                        y = ampli * exp(1i*2*pi*f*(x + app.DesplazamientoSpinner.Value)) .* exp(-(x + app.DesplazamientoSpinner.Value));
                        yn = ampli * exp(1i*2*pi*f*(xn + app.DesplazamientoSpinner.Value)) .* exp(-(xn + app.DesplazamientoSpinner.Value));
                        % Graficar ambas señales
                        plot(app.UIAxes,x, y); % Grafica la señal continua
                        title(app.UIAxes,'Señal Continua f(t) - Exponencial compleja'); % Título de la subtrama
                        xlabel(app.UIAxes,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes,'Amplitud (m)'); % Etiqueta del eje y
                        
                        stem(app.UIAxes2,xn, yn); % Grafica la señal discreta utilizando un gráfico de tallos
                        title(app.UIAxes2,'Señal Discreta f[t] - Exponencial compleja');
                        xlabel(app.UIAxes2,'Tiempo (s)'); % Etiqueta del eje x
                        ylabel(app.UIAxes2,'Amplitud (m)'); % Etiqueta del eje y
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @ButtonGenerarPushed, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ButtonGenerar = uibutton(app.UIFigure, 'push');
            app.ButtonGenerar.ButtonPushedFcn = createCallbackFcn(app, @ButtonGenerarPushed, true);
            app.ButtonGenerar.Text = 'Generar señal';
            app.SealLabel = uilabel(app.UIFigure);
            app.SealLabel.Text = 'Tipo de señal';
            app.DropDownSenales = uidropdown(app.UIFigure);
            app.DropDownSenales.Items = {'Escalón unitario', 'Impulso unitario', 'Rampa', 'Senoidal', 'Senoidal compleja', 'Exponencial', 'Exponencial compleja', 'Pulso triangular', 'Pulso rectangular'};
            app.DropDownSenales.Value = 'Escalón unitario';
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditField.Value = -10;
            app.tfEditFieldLabel = uilabel(app.UIFigure);
            app.tfEditFieldLabel.Text = 'tf';
            app.tfEditField = uieditfield(app.UIFigure, 'numeric');
            app.tfEditField.Value = 10;
            app.GeneradordesealesLabel = uilabel(app.UIFigure);
            app.GeneradordesealesLabel.Text = 'Generador de señales';
            app.TiempodelasealLabel = uilabel(app.UIFigure);
            app.TiempodelasealLabel.Text = 'Tiempo de la señal';
            app.FrecuenciaSpinnerLabel = uilabel(app.UIFigure);
            app.FrecuenciaSpinnerLabel.Text = 'Frecuencia';
            app.FrecuenciaSpinner = uispinner(app.UIFigure);
            app.FrecuenciaSpinner.Value = 1;
            app.AmplitudSpinnerLabel = uilabel(app.UIFigure);
            app.AmplitudSpinnerLabel.Text = 'Amplitud';
            app.AmplitudSpinner = uispinner(app.UIFigure);
            app.AmplitudSpinner.Value = 1;
            app.FrecuenciademuestreoporsegundoSpinnerLabel = uilabel(app.UIFigure);
            app.FrecuenciademuestreoporsegundoSpinnerLabel.Text = 'Frecuencia de muestreo por segundo';
            app.FrecuenciademuestreoporsegundoSpinner = uispinner(app.UIFigure);
            app.FrecuenciademuestreoporsegundoSpinner.Limits = [0 1];
            app.FrecuenciademuestreoporsegundoSpinner.Value = 0.1;
            app.DesplazamientoSpinnerLabel = uilabel(app.UIFigure);
            app.DesplazamientoSpinnerLabel.Text = 'Desplazamiento';
            app.DesplazamientoSpinner = uispinner(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practica3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end